classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneralContainer          matlab.ui.Figure
        SuperGrid                 matlab.ui.container.GridLayout
        CopntrolGrid              matlab.ui.container.GridLayout
        ValueGrid                 matlab.ui.container.GridLayout
        GesamtwertField           matlab.ui.control.EditField
        ErkannterGesamtwertLabel  matlab.ui.control.Label
        RequestGrid               matlab.ui.container.GridLayout
        WunschwertField           matlab.ui.control.NumericEditField
        GewnschterWertLabel       matlab.ui.control.Label
        CoinSelection             matlab.ui.container.ButtonGroup
        LargeCoins                matlab.ui.control.RadioButton
        SmallCoins                matlab.ui.control.RadioButton
        Logo                      matlab.ui.control.Image
        ConfirmButton             matlab.ui.control.Button
        ImportButton              matlab.ui.control.Button
        ImageGrid                 matlab.ui.container.GridLayout
        IModified                 matlab.ui.control.UIAxes
        IOrig                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Io; % Original Image
        Im; % Modified Image
        trainedNetwork; % Loaded network .m file
        cells; % Current cells
    end
    
    methods (Access = private)
        function resetImages(app)                    
            app.Im = app.Io;
            displayUpdatedImages(app);
        end
        
        function displayUpdatedImages(app)
            imshow(app.Io, 'Parent', app.IOrig);
            imshow(app.Im, 'Parent', app.IModified);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            app.Io = imread('muenzenVerdeckung.jpg');
            resetImages(app);
            % TODO: serializedNet laden
            load serializedNet.mat net
            app.trainedNetwork = net;
        end

        % Button pushed function: ImportButton
        function OnLoadImageButtonPushed(app, event)
            filename = imgetfile;
            app.Io = imread(filename);
            resetImages(app);
            % TODO: Cut (aus main.mlx) ausführen
            % cells = <cut-funktion>
            % TODO: Klassifizierung (aus image_classification.mlx)
            % ausführen
            % cells = <klassifizierungs-funktion>
            % TODO: Münzwerte für "aktueller Betrag" zusammenrechnen
            % TODO: Aktuellen Filter nochmal applien ODER auf NONE setzen
        end

        % Button pushed function: ConfirmButton
        function OnConfirmButtonPushed(app, event)
            filterType = PostworkFilterType.NONE;
            if (app.SmallCoins.Value)
                filterType = PostworkFilterType.AMOUNT_MIN_COIN_COUNT;
            elseif (app.LargeCoins.Value)
                filterType = PostworkFilterType.AMOUNT_MIN_COIN_VALUE;
            end
            % TODO: Für die restlichen Radio Buttons filterType setzen
            % TODO: Filterung (aus postwork) ausführen
            % Im = <postwork-funktion>
            displayUpdatedImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GeneralContainer = uifigure('Visible', 'off');
            app.GeneralContainer.Name = 'CoinCat';
            app.GeneralContainer.Icon = fullfile(pathToMLAPP, 'Logo', 'CoinCatLogoHead.png');
            app.SuperGrid = uigridlayout(app.GeneralContainer);
            app.ImageGrid = uigridlayout(app.SuperGrid);
            app.IOrig = uiaxes(app.ImageGrid);
            app.IOrig.XTick = [];
            app.IOrig.YTick = [];
            app.IModified = uiaxes(app.ImageGrid);
            xlabel(app.IModified, 'X')
            ylabel(app.IModified, 'Y')
            zlabel(app.IModified, 'Z')
            app.CopntrolGrid = uigridlayout(app.SuperGrid);
            app.ImportButton = uibutton(app.CopntrolGrid, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @OnLoadImageButtonPushed, true);
            app.ConfirmButton = uibutton(app.CopntrolGrid, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @OnConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Button2';
            app.Logo = uiimage(app.CopntrolGrid);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'Logo', 'CoinCatLogoHead.png');
            app.CoinSelection = uibuttongroup(app.CopntrolGrid);
            app.SmallCoins = uiradiobutton(app.CoinSelection);
            app.SmallCoins.Text = 'Geringer Wert';
            app.SmallCoins.Value = true;
            app.LargeCoins = uiradiobutton(app.CoinSelection);
            app.LargeCoins.Text = 'Wenige Münzen';
            app.RequestGrid = uigridlayout(app.CopntrolGrid);
            app.GewnschterWertLabel = uilabel(app.RequestGrid);
            app.GewnschterWertLabel.Text = 'Gewünschter Wert:';
            app.WunschwertField = uieditfield(app.RequestGrid, 'numeric');
            app.ValueGrid = uigridlayout(app.CopntrolGrid);
            app.ErkannterGesamtwertLabel = uilabel(app.ValueGrid);
            app.ErkannterGesamtwertLabel.Text = 'Erkannter Gesamtwert:';
            app.GesamtwertField = uieditfield(app.ValueGrid, 'text');
            % Show the figure after all components are created
            app.GeneralContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneralContainer)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneralContainer)
        end
    end
end